classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ParametersPanel             matlab.ui.container.Panel
        RecordAudioParametersLabel  matlab.ui.control.Label
        PitchShiftParametersLabel   matlab.ui.control.Label
        DeviceIDEditFieldLabel      matlab.ui.control.Label
        DeviceIDEditField           matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField  matlab.ui.control.NumericEditField
        NoofBitsEditFieldLabel      matlab.ui.control.Label
        NoofBitsEditField           matlab.ui.control.NumericEditField
        NumberofSemitonesEditFieldLabel  matlab.ui.control.Label
        NumberofSemitonesEditField  matlab.ui.control.NumericEditField
        Panel2                      matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        AudioPitchShiftingDisplayingofGraphLabel  matlab.ui.control.Label
        ProgramStatusLabel          matlab.ui.control.Label
        ProgramStatusTextArea       matlab.ui.control.TextArea
        StartRecordingButton        matlab.ui.control.Button
        PerformOperationButton      matlab.ui.control.Button
        PlayOriginalSignalButton    matlab.ui.control.Button
        PlayShiftedAudioButton      matlab.ui.control.Button
        GenerateGraphsButton        matlab.ui.control.Button
        ApplicationByChristopherNiloACaysidoForCPE122LA1Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        time_dom % Time Domain
        record_audio % Record of Audio Object
        record_data % Data of the audio file
        pitchshift_audio % Pitchshit Audio Object
        pitchshift_data % Pitchshit audio data
        player_obj_1 % Playable Audio Object 
        player_obj_2 % Playable Shifted Object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
        Device = app.DeviceIDEditField.Value;
        Sampling_Freq = app.SamplingFrequencyEditField.Value;
        Bits = app.NoofBitsEditField.Value;
        app.record_audio = audiorecorder(Sampling_Freq,Bits,1,Device);
        app.ProgramStatusTextArea.Value = 'Start Speaking Now';
        recordblocking(app.record_audio,7);
        app.ProgramStatusTextArea.Value = 'Audio Has Been Recorded';
        app.player_obj_1 = audioplayer(app.record_audio);
        app.record_data = getaudiodata(app.record_audio);
        end

        % Button pushed function: PerformOperationButton
        function PerformOperationButtonPushed(app, event)
        semitones = app.NumberofSemitonesEditField.Value;
        app.pitchshift_audio = shiftPitch(app.record_data,app.NumberofSemitonesEditField.Value);
        app.ProgramStatusTextArea.Value = 'Applied Pitch Shift to Original Audio Signal';
        end

        % Button pushed function: PlayOriginalSignalButton
        function PlayOriginalSignalButtonPushed(app, event)
            play(app.player_obj_1);
        end

        % Button pushed function: PlayShiftedAudioButton
        function PlayShiftedAudioButtonPushed(app, event)
            sound(app.pitchshift_audio);
        end

        % Button pushed function: GenerateGraphsButton
        function GenerateGraphsButtonPushed(app, event)
            plot(app.UIAxes,app.record_data);
            plot(app.UIAxes2,app.pitchshift_audio);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.RecordAudioParametersLabel = uilabel(app.ParametersPanel);
            app.RecordAudioParametersLabel.Text = 'Record Audio Parameters';
            app.PitchShiftParametersLabel = uilabel(app.ParametersPanel);
            app.PitchShiftParametersLabel.Text = 'Pitch Shift Parameters';
            app.DeviceIDEditFieldLabel = uilabel(app.ParametersPanel);
            app.DeviceIDEditFieldLabel.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DeviceIDEditField.ValueDisplayFormat = '%.0f';
            app.DeviceIDEditField.Value = 2;
            app.SamplingFrequencyEditFieldLabel = uilabel(app.ParametersPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SamplingFrequencyEditField.ValueDisplayFormat = '%.0f';
            app.SamplingFrequencyEditField.Value = 11025;
            app.NoofBitsEditFieldLabel = uilabel(app.ParametersPanel);
            app.NoofBitsEditFieldLabel.Text = 'No. of Bits';
            app.NoofBitsEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NoofBitsEditField.ValueDisplayFormat = '%.0f';
            app.NoofBitsEditField.Value = 8;
            app.NumberofSemitonesEditFieldLabel = uilabel(app.ParametersPanel);
            app.NumberofSemitonesEditFieldLabel.Text = 'Number of Semitones';
            app.NumberofSemitonesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NumberofSemitonesEditField.ValueDisplayFormat = '%.1f';
            app.NumberofSemitonesEditField.Value = 8;
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.UIAxes = uiaxes(app.Panel2);
            title(app.UIAxes, 'Original Audio Signal')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.Panel2);
            title(app.UIAxes2, 'Pitch Shift Graph')
            xlabel(app.UIAxes2, 'Samples')
            ylabel(app.UIAxes2, {'Amplitude'; ''})
            app.UIAxes2.XTick = [0 1 2 3 4 5 6 7];
            app.UIAxes2.XTickLabel = {'0'; '1'; '0'; '1'; '0'; '5'; '6'};
            app.AudioPitchShiftingDisplayingofGraphLabel = uilabel(app.UIFigure);
            app.AudioPitchShiftingDisplayingofGraphLabel.Text = {'Audio Pitch Shifting '; 'Displaying of Graph'};
            app.ProgramStatusLabel = uilabel(app.UIFigure);
            app.ProgramStatusLabel.Text = {'Program'; ' Status'};
            app.ProgramStatusTextArea = uitextarea(app.UIFigure);
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.PerformOperationButton = uibutton(app.UIFigure, 'push');
            app.PerformOperationButton.ButtonPushedFcn = createCallbackFcn(app, @PerformOperationButtonPushed, true);
            app.PerformOperationButton.Text = 'Perform Operation';
            app.PlayOriginalSignalButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalSignalButtonPushed, true);
            app.PlayOriginalSignalButton.Text = 'Play Original Signal';
            app.PlayShiftedAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayShiftedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayShiftedAudioButtonPushed, true);
            app.PlayShiftedAudioButton.Text = 'Play Shifted Audio';
            app.GenerateGraphsButton = uibutton(app.UIFigure, 'push');
            app.GenerateGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphsButtonPushed, true);
            app.GenerateGraphsButton.Text = 'Generate Graphs';
            app.ApplicationByChristopherNiloACaysidoForCPE122LA1Label = uilabel(app.UIFigure);
            app.ApplicationByChristopherNiloACaysidoForCPE122LA1Label.Text = {'Application By Christopher Nilo A. Caysido'; 'For CPE122L - A1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end